classdef masterEquationSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        tunnelcapacitorC1FEditFieldLabel  matlab.ui.control.Label
        tunnelcapacitorC1FEditField     matlab.ui.control.NumericEditField
        tunnelcapacitorC2FEditFieldLabel  matlab.ui.control.Label
        tunnelcapacitorC2FEditField     matlab.ui.control.NumericEditField
        gatecapacitorCgFEditFieldLabel  matlab.ui.control.Label
        gatecapacitorCgFEditField       matlab.ui.control.NumericEditField
        tunnelresistanceR1MegaOhmEditFieldLabel  matlab.ui.control.Label
        tunnelresistanceR1MegaOhmEditField  matlab.ui.control.NumericEditField
        tunnelresistanceR2MegaOhmEditFieldLabel  matlab.ui.control.Label
        tunnelresistanceR2MegaOhmEditField  matlab.ui.control.NumericEditField
        gatevoltageVEditFieldLabel      matlab.ui.control.Label
        gatevoltageVEditField           matlab.ui.control.NumericEditField
        backgroundchargeq0isassumedtobezeroLabel  matlab.ui.control.Label
        backgroundchargeq0isassumedtobezeroEditField  matlab.ui.control.NumericEditField
        temperatureTKEditFieldLabel     matlab.ui.control.Label
        temperatureTKEditField          matlab.ui.control.NumericEditField
        drainvoltageminimumVminVEditFieldLabel  matlab.ui.control.Label
        drainvoltageminimumVminVEditField  matlab.ui.control.NumericEditField
        drainvoltagemaximumVmaxVEditFieldLabel  matlab.ui.control.Label
        drainvoltagemaximumVmaxVEditField  matlab.ui.control.NumericEditField
        minimumnumberofNchargenumberindotEditFieldLabel  matlab.ui.control.Label
        minimumnumberofNchargenumberindotEditField  matlab.ui.control.NumericEditField
        maximumnumberofNchargenumberindotEditFieldLabel  matlab.ui.control.Label
        maximumnumberofNchargenumberindotEditField  matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        PrincipalInterestUIAxes         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {475, 475};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {413, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function jindicedellajesimagammaButtonPushed(app, event)
                       
            % Calculate the monthly payment
            amount = app.tunnelcapacitorC1FEditField.Value ;
            rate = app.tunnelcapacitorC2FEditField.Value/12/100 ;
            nper = 12*app.gatecapacitorCgFEditField.Value ;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables 
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate ;
                principal(i) = payment - interest(i) ;
                balance(i+1) = balance(i) - principal(i) ;
            end
            
            % Plot the principal and interest
            plot(app.PrincipalInterestUIAxes, (1:nper)', principal, ...
                (1:nper)', interest) ;
            legend(app.PrincipalInterestUIAxes,{'Principal','Interest'},'Location','Best')
            xlim(app.PrincipalInterestUIAxes,[0 nper]) ; 
            
        end

        % Value changed function: tunnelcapacitorC1FEditField
        function tunnelcapacitorC1FEditFieldValueChanged(app, event)
            value = app.tunnelcapacitorC1FEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.tunnelcapacitorC1FEditFieldLabel = uilabel(app.LeftPanel);
            app.tunnelcapacitorC1FEditFieldLabel.Text = 'tunnel capacitor C1 (F)';
            app.tunnelcapacitorC1FEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tunnelcapacitorC1FEditField.ValueChangedFcn = createCallbackFcn(app, @tunnelcapacitorC1FEditFieldValueChanged, true);
            app.tunnelcapacitorC1FEditField.Value = 1e-20;
            app.tunnelcapacitorC2FEditFieldLabel = uilabel(app.LeftPanel);
            app.tunnelcapacitorC2FEditFieldLabel.Text = 'tunnel capacitor C2 (F)';
            app.tunnelcapacitorC2FEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tunnelcapacitorC2FEditField.Value = 2.1e-19;
            app.gatecapacitorCgFEditFieldLabel = uilabel(app.LeftPanel);
            app.gatecapacitorCgFEditFieldLabel.Text = 'gate capacitor Cg (F)';
            app.gatecapacitorCgFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.gatecapacitorCgFEditField.Value = 1e-18;
            app.tunnelresistanceR1MegaOhmEditFieldLabel = uilabel(app.LeftPanel);
            app.tunnelresistanceR1MegaOhmEditFieldLabel.Text = 'tunnel resistance R1 (MegaOhm)';
            app.tunnelresistanceR1MegaOhmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tunnelresistanceR1MegaOhmEditField.Value = 15;
            app.tunnelresistanceR2MegaOhmEditFieldLabel = uilabel(app.LeftPanel);
            app.tunnelresistanceR2MegaOhmEditFieldLabel.Text = 'tunnel resistance R2 (MegaOhm)';
            app.tunnelresistanceR2MegaOhmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tunnelresistanceR2MegaOhmEditField.Value = 250;
            app.gatevoltageVEditFieldLabel = uilabel(app.LeftPanel);
            app.gatevoltageVEditFieldLabel.Text = 'gate voltage (V)';
            app.gatevoltageVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.backgroundchargeq0isassumedtobezeroLabel = uilabel(app.LeftPanel);
            app.backgroundchargeq0isassumedtobezeroLabel.Text = 'background charge q0 is assumed to be zero';
            app.backgroundchargeq0isassumedtobezeroEditField = uieditfield(app.LeftPanel, 'numeric');
            app.temperatureTKEditFieldLabel = uilabel(app.LeftPanel);
            app.temperatureTKEditFieldLabel.Text = 'temperature T (K)';
            app.temperatureTKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.temperatureTKEditField.Value = 10;
            app.drainvoltageminimumVminVEditFieldLabel = uilabel(app.LeftPanel);
            app.drainvoltageminimumVminVEditFieldLabel.Text = 'drain voltage minimum Vmin (V)';
            app.drainvoltageminimumVminVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.drainvoltageminimumVminVEditField.Value = -0.5;
            app.drainvoltagemaximumVmaxVEditFieldLabel = uilabel(app.LeftPanel);
            app.drainvoltagemaximumVmaxVEditFieldLabel.Text = 'drain voltage maximum Vmax (V)';
            app.drainvoltagemaximumVmaxVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.drainvoltagemaximumVmaxVEditField.Value = 0.5;
            app.minimumnumberofNchargenumberindotEditFieldLabel = uilabel(app.LeftPanel);
            app.minimumnumberofNchargenumberindotEditFieldLabel.Text = 'minimum number of N (charge number in dot)';
            app.minimumnumberofNchargenumberindotEditField = uieditfield(app.LeftPanel, 'numeric');
            app.minimumnumberofNchargenumberindotEditField.Value = -20;
            app.maximumnumberofNchargenumberindotEditFieldLabel = uilabel(app.LeftPanel);
            app.maximumnumberofNchargenumberindotEditFieldLabel.Text = 'maximum number of N (charge number in dot)';
            app.maximumnumberofNchargenumberindotEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maximumnumberofNchargenumberindotEditField.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'plot of I vs V')
            xlabel(app.PrincipalInterestUIAxes, 'Drain voltage V')
            ylabel(app.PrincipalInterestUIAxes, 'Current I')
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'plot of dI/dV vs V')
            xlabel(app.UIAxes, 'Drain voltage V')
            ylabel(app.UIAxes, 'dI/dV')
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = masterEquationSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end