classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Gamma              matlab.ui.control.Button
        Plot               matlab.ui.control.UIAxes
        nSpinnerLabel      matlab.ui.control.Label
        nSpinner           matlab.ui.control.Spinner
        V_GLabel           matlab.ui.control.Label
        V_GEditField       matlab.ui.control.NumericEditField
        V_DEditFieldLabel  matlab.ui.control.Label
        V_DEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Gamma
        function GammaPushed(app, event)
            y = [];
            n = app.nSpinner.Value;
            for i = -n : n
                y = [y; f_tunnel0(1,i,app.V_DEditField.Value,app.V_GEditField.Value)];
            end;
            %%% plot dei 4 tipi di gamma(n) per n che va da -10 a 10
            app.Plot.Title.String="\Gamma";
            plot(app.Plot,-n:n, y(:,1), -n:n, y(:,2), -n:n, y(:,3), -n:n, y(:,4));
            xlabel (app.Plot,'Numero di cariche nel dot (n)');
            ylabel (app.Plot,'Rate di tunneling (\Gamma_n)');
            legend (app.Plot,'\Gamma_{source->dot}', '\Gamma_{dot->source}', '\Gamma_{dot->drain}', '\Gamma_{drain->dot}')
        end

        % Callback function
        function nSpinnerValueChanging(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Gamma = uibutton(app.UIFigure, 'push');
            app.Gamma.ButtonPushedFcn = createCallbackFcn(app, @GammaPushed, true);
            app.Gamma.Text = 'Plot Gamma';
            app.Plot = uiaxes(app.UIFigure);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            app.nSpinnerLabel = uilabel(app.UIFigure);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.UIFigure);
            app.V_GLabel = uilabel(app.UIFigure);
            app.V_GLabel.Text = 'V_G';
            app.V_GEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_DEditFieldLabel = uilabel(app.UIFigure);
            app.V_DEditFieldLabel.Text = 'V_D';
            app.V_DEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end